classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        ConnexionPanel                 matlab.ui.container.Panel
        CommencerlalectureSwitchLabel  matlab.ui.control.Label
        Connect                        matlab.ui.control.Switch
        PortCOMDropDownLabel           matlab.ui.control.Label
        PortCOMDropDown                matlab.ui.control.DropDown
        LumiresPanel                   matlab.ui.container.Panel
        RampeLampLabel                 matlab.ui.control.Label
        RampeLamp                      matlab.ui.control.Lamp
        WarningLampLabel               matlab.ui.control.Label
        WarningLamp                    matlab.ui.control.Lamp
        ClignogaucheLampLabel          matlab.ui.control.Label
        ClignogaucheLamp               matlab.ui.control.Lamp
        ClignodroitLampLabel           matlab.ui.control.Label
        ClignodroitLamp                matlab.ui.control.Lamp
        PhareLampLabel                 matlab.ui.control.Label
        PhareLamp                      matlab.ui.control.Lamp
        Panel                          matlab.ui.container.Panel
        PressiondhuileLampLabel        matlab.ui.control.Label
        PressiondhuileLamp             matlab.ui.control.Lamp
        EssenceGaugeLabel              matlab.ui.control.Label
        EssenceGauge                   matlab.ui.control.LinearGauge
        RPMGaugeLabel                  matlab.ui.control.Label
        RPMGauge                       matlab.ui.control.SemicircularGauge
        BatterieGaugeLabel             matlab.ui.control.Label
        BatterieGauge                  matlab.ui.control.LinearGauge
        TempraturemoteurGaugeLabel     matlab.ui.control.Label
        TempraturemoteurGauge          matlab.ui.control.LinearGauge
        VitessePanel                   matlab.ui.container.Panel
        Vitesse                        matlab.ui.control.Label
    end


    properties (Access = private)
        s % Serial port
        readLoop %
        valData % Données du tableau
        idInfo = {} % Données en fonction des ID
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.valData = {0,0,'RPM',0};


            %Ensemble des informations liées à chaque ID : %RPM %Unité %Range
            %Système
                % Créer une matrice de 255
                app.idInfo = repmat({'','',''},256,1);
                
                % Attribuer les infos en fonction des ID
                app.idInfo(50,:) = {'RPM','Tour/min',10000};
                app.idInfo(54,:) = {'Pression huile','',0};
                app.idInfo(58,:) = {'Vitesse','Km/h',0};
                app.idInfo(62,:) = {'Température moteur','°C',0};
                app.idInfo(66,:) = {'Niveau essence','Pourcentage',0};
                app.idInfo(70,:) = {'Batterie','mV',0};
            %Temps
                app.idInfo(100,:) = {'Heure','s',0};
                app.idInfo(104,:) = {'Temps/tour','ms',0};
                app.idInfo(108,:) = {'Temps de session','s',0};
                
            %Lumières
                app.idInfo(120,:) = {'Rampe','',0};
                app.idInfo(124,:) = {'Warning','',0};
                app.idInfo(128,:) = {'Cligno gauche','',0};
                app.idInfo(132,:) = {'Cligno droit','',0};
            app.valData = {};                
           app.UITable.Data = app.valData; 
            % #1 Drop Down Menu
            app.PortCOMDropDown.Items = cellstr(seriallist);


        end

        % Value changed function: Connect
        function Connecting(app, event)
            value = app.Connect.Value;
            %disp(strcmp(value,'Off'))
            if strcmp(value,'Off')
                disp('Connexion série interrompue')
                app.readLoop = 0; % Désactiver la lecture
                
            else
                tic
                app.readLoop = 1;
                app.s = serial(app.PortCOMDropDown.Value);
                app.s.Baudrate = 115200;
                fopen(app.s)
                
                while 1
                    
                    busData  = str2num(fgetl(app.s)); % Récuperer ce qu'il y a sur le BUS série
                    %                     busData  = str2num(fgetl(s));
                    if ~isempty(busData) % Si la donnée du bus n'est pas vide
                        % busData(1) -> ID
                        % busData(2) -> Valeur


                        if ~(size(app.valData) == 0) % Vérifie si la matrice est vide, si c'est pas le cas on chercher l'ID

                            
                            index = find([app.valData{:,2}] == busData(1)); % Cherche la ligne où se trouve l'ID
                            
                            % Si l'ID est pas dans la matrice de  données -> initialiser
                            if  isempty(index)
                                [index,~] = size(app.valData);
                                index = index + 1;
                                app.valData(index,:) = {toc,busData(1),char(app.idInfo(busData(1),1)),busData(2),char(app.idInfo(busData(1),2))};
                            else
                                % Si l'ID est déjà présent dans la matrice, il suffit de changer sa valeur
                                app.valData{index,1} = toc; %Timestamp
                                app.valData{index,4} = busData(2); % Valeur
   
                            end


                        else
                            % Si c'est la première fois qu'on fait la boucle: Initalisation
                            index =1;
                            app.valData(index,:) = {toc,busData(1),char(app.idInfo(busData(1),1)),busData(2),char(app.idInfo(busData(1),2))};
                            
                        end 
                        
                        valeur = busData(2);
                        switch busData(1)
                            case 50
                                app.RPMGauge.Value = valeur/1000;
                            case 54
                                
                                if valeur
                                    app.PressiondhuileLamp.Color = 'green';
                                else
                                    app.PressiondhuileLamp.Color = 'red';
                                end
                            case 58
                                 app.Vitesse.Text = num2str(valeur);
                            case 62
                                app.TempraturemoteurGauge.Value = valeur;
                            case 66
                                app.EssenceGauge.Value = valeur;
                            case 70
                                app.BatterieGauge.Value = valeur/1000; 
                            case 120
                                
                                if valeur
                                    app.RampeLamp.Color = 'green';
                                else
                                    app.RampeLamp.Color = 'white';
                                end
                            case 124
                                
                                if valeur
                                    app.WarningLamp.Color = 'green';
                                else
                                    app.WarningLamp.Color = 'white';
                                end
                            case 128
                                
                                if valeur
                                    app.ClignogaucheLamp.Color = 'green';
                                else
                                    app.ClignogaucheLamp.Color = 'white';
                                end
                            case 132
                                
                                if valeur
                                    app.ClignodroitLamp.Color = 'green';
                                else
                                    app.ClignodroitLamp.Color = 'white';
                                end
                            case 136
                                
                                if valeur
                                    app.PhareLamp.Color = 'green';
                                else
                                    app.PhareLamp.Color = 'white';
                                end
                        end
                      
                        
%                         app.valData{index,1} = toc; %Timestamp
%                         app.valData{index,2} = busData(1); % ID
% 
%                         app.valData{index,3} = char(app.idInfo(busData(1),1)); % Nom de l'ID
%                         app.valData{index,4} = busData(2); % Valeur
%                         app.valData{index,5} = char(app.idInfo(busData(1),2)); % Unité


                        % Rajouter / Modifier la ligner =
                        % Timestampe - ID - Nom de l'ID - Valeur - Unité

%                         app.valData(index,:) = {toc,busData(1),char(app.idInfo(busData(1),1)),busData(2),char(app.idInfo(busData(1),2))};
                        app.UITable.Data = app.valData;
                    end
                    pause(0.05)
                    if not(app.readLoop)
                        break
                    end

                end
                fclose(app.s)
                delete(app.s)
            
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            try
                fclose(app.s)
                delete(app.s)
                disp('Connexion série interrompue')
            end
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Timestamp'; 'ID'; 'Nom'; 'Value'; 'Unité'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false false true];
            app.ConnexionPanel = uipanel(app.UIFigure);
            app.ConnexionPanel.Title = 'Connexion';
            app.CommencerlalectureSwitchLabel = uilabel(app.ConnexionPanel);
            app.CommencerlalectureSwitchLabel.Text = 'Commencer la lecture';
            app.Connect = uiswitch(app.ConnexionPanel, 'slider');
            app.Connect.ValueChangedFcn = createCallbackFcn(app, @Connecting, true);
            app.PortCOMDropDownLabel = uilabel(app.ConnexionPanel);
            app.PortCOMDropDownLabel.Text = 'Port COM';
            app.PortCOMDropDown = uidropdown(app.ConnexionPanel);
            app.PortCOMDropDown.Items = {};
            app.PortCOMDropDown.Value = {};
            app.LumiresPanel = uipanel(app.UIFigure);
            app.LumiresPanel.Title = 'Lumières';
            app.RampeLampLabel = uilabel(app.LumiresPanel);
            app.RampeLampLabel.Text = 'Rampe';
            app.RampeLamp = uilamp(app.LumiresPanel);
            app.WarningLampLabel = uilabel(app.LumiresPanel);
            app.WarningLampLabel.Text = 'Warning';
            app.WarningLamp = uilamp(app.LumiresPanel);
            app.ClignogaucheLampLabel = uilabel(app.LumiresPanel);
            app.ClignogaucheLampLabel.Text = 'Cligno gauche';
            app.ClignogaucheLamp = uilamp(app.LumiresPanel);
            app.ClignodroitLampLabel = uilabel(app.LumiresPanel);
            app.ClignodroitLampLabel.Text = 'Cligno droit';
            app.ClignodroitLamp = uilamp(app.LumiresPanel);
            app.PhareLampLabel = uilabel(app.LumiresPanel);
            app.PhareLampLabel.Text = 'Phare';
            app.PhareLamp = uilamp(app.LumiresPanel);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.PressiondhuileLampLabel = uilabel(app.Panel);
            app.PressiondhuileLampLabel.Text = 'Pression d''huile';
            app.PressiondhuileLamp = uilamp(app.Panel);
            app.EssenceGaugeLabel = uilabel(app.Panel);
            app.EssenceGaugeLabel.Text = 'Essence';
            app.EssenceGauge = uigauge(app.Panel, 'linear');
            app.RPMGaugeLabel = uilabel(app.Panel);
            app.RPMGaugeLabel.Text = 'RPM';
            app.RPMGauge = uigauge(app.Panel, 'semicircular');
            app.RPMGauge.Limits = [0 10];
            app.RPMGauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.BatterieGaugeLabel = uilabel(app.Panel);
            app.BatterieGaugeLabel.Text = 'Batterie';
            app.BatterieGauge = uigauge(app.Panel, 'linear');
            app.BatterieGauge.Limits = [0 15];
            app.TempraturemoteurGaugeLabel = uilabel(app.Panel);
            app.TempraturemoteurGaugeLabel.Text = 'Température moteur';
            app.TempraturemoteurGauge = uigauge(app.Panel, 'linear');
            app.TempraturemoteurGauge.Limits = [0 140];
            app.VitessePanel = uipanel(app.UIFigure);
            app.VitessePanel.Title = 'Vitesse';
            app.Vitesse = uilabel(app.VitessePanel);
            app.Vitesse.Text = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end